classdef MotionTrackingDisplay_Syndesmose < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SaveButton            matlab.ui.control.Button
        DisplayTImestepLabel  matlab.ui.control.Label
        TimestepSlider        matlab.ui.control.Slider
        TimestepSliderLabel   matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        positionFib
        positionTib
        positionTib_PoI
        hFib
        hTib
        hTib_PoI
        numTimesteps
        dataDir
        zDisplayRange = [1270, 1340]
    end

    % Function to load data and initialize the plot
    methods (Access = private)

        function points = cropToDisplayRange(app, points)
            if isempty(points)
                return
            end

            keepMask = points(3, :) >= app.zDisplayRange(1) & ...
                points(3, :) <= app.zDisplayRange(2);
            points = points(:, keepMask);
        end

        function loadData(app)
            % Load all .mat files for Fib and Tib segments and store positions
            timestep = 1;
            positionDataFib = {};
            positionDataTib = {};
            positionDataTib_PoI = {};

            while true
                % Load Fib segment data
                timestamp_label_fib = sprintf('Fib_Seg_%04d.mat', timestep);
                fileNameFib = fullfile(app.dataDir, timestamp_label_fib);

                if ~isfile(fileNameFib)
                    break;
                end

                loadedDataFib = load(fileNameFib);
                varNamesFib = fieldnames(loadedDataFib);
                positionDataFib{timestep} = app.cropToDisplayRange(loadedDataFib.(varNamesFib{1}));

                % Load corresponding Tib segment data
                timestamp_label_tib = sprintf('Tib_Seg_%04d.mat', timestep);
                fileNameTib = fullfile(app.dataDir, timestamp_label_tib);

                if ~isfile(fileNameTib)
                    break;
                end

                loadedDataTib = load(fileNameTib);
                varNamesTib = fieldnames(loadedDataTib);
                positionDataTib{timestep} = app.cropToDisplayRange(loadedDataTib.(varNamesTib{1}));

                % Load corresponding Tib Point of Interest data
                timestamp_label_tib_PoI = sprintf('Tib_PoI_%04d.mat', timestep);
                fileNameTib_PoI = fullfile(app.dataDir, timestamp_label_tib_PoI);

                if ~isfile(fileNameTib_PoI)
                    break;
                end

                loadedDataTib_PoI = load(fileNameTib_PoI);
                varNamesTib_PoI = fieldnames(loadedDataTib_PoI);
                positionDataTib_PoI{timestep} = loadedDataTib_PoI.(varNamesTib_PoI{1});

                timestep = timestep + 1;
            end

            % Store positions for Fib
            app.numTimesteps = length(positionDataFib);
            app.positionFib = cell(1, app.numTimesteps);
            for t = 1:app.numTimesteps
                app.positionFib{t} = positionDataFib{t};
            end

            % Store positions for Tib
            app.positionTib = cell(1, app.numTimesteps);
            for t = 1:app.numTimesteps
                app.positionTib{t} = positionDataTib{t};
            end

            % Store positions for Tib Point of Interest
            app.positionTib_PoI = cell(1, app.numTimesteps);
            for t = 1:app.numTimesteps
                app.positionTib_PoI{t} = positionDataTib_PoI{t};
            end
        end


        function initializePlot(app)
            % Initialize plot with the first timestep for both Fib and Tib
            % Plot Fib segment data
            if ~isempty(app.positionFib{1})
                app.hFib = plot3(app.UIAxes, ...
                    app.positionFib{1}(1, :), ...
                    app.positionFib{1}(2, :), ...
                    app.positionFib{1}(3, :), ...
                    '.', 'MarkerSize', 7);
                app.hFib.Annotation.LegendInformation.IconDisplayStyle = 'off';
                hold(app.UIAxes, 'on');
            end

            if ~isempty(app.positionTib{1})
                app.hTib = plot3(app.UIAxes, ...
                    app.positionTib{1}(1, :), ...
                    app.positionTib{1}(2, :), ...
                    app.positionTib{1}(3, :), ...
                    '.', 'MarkerSize', 7);
                app.hTib.Annotation.LegendInformation.IconDisplayStyle = 'off';
            end

            if ~isempty(app.positionTib_PoI{1})
                app.hTib_PoI = plot3(app.UIAxes, ...
                    app.positionTib_PoI{1}(1, :), ...
                    app.positionTib_PoI{1}(2, :), ...
                    app.positionTib_PoI{1}(3, :), ...
                    'o', ...
                    'DisplayName', 'Tibial PoI', ...
                    'MarkerSize', 10, ...
                    'MarkerFaceColor', 'r', ...
                    'MarkerEdgeColor', 'k', ...
                    'LineWidth', 1.25);
            end
            hold(app.UIAxes, 'off');

            app.DisplayTImestepLabel.Text = 'Timestep: 1';

            
            xLimits = [min(cellfun(@(x) min(x(1, :), [], 'omitnan'), app.positionFib)), ...
                min(cellfun(@(x) min(x(1, :), [], 'omitnan'), app.positionTib)), ...
                max(cellfun(@(x) max(x(1, :), [], 'omitnan'), app.positionFib)), ...
                max(cellfun(@(x) max(x(1, :), [], 'omitnan'), app.positionTib))];

            yLimits = [min(cellfun(@(x) min(x(2, :), [], 'omitnan'), app.positionFib)), ...
                min(cellfun(@(x) min(x(2, :), [], 'omitnan'), app.positionTib)), ...
                max(cellfun(@(x) max(x(2, :), [], 'omitnan'), app.positionFib)), ...
                max(cellfun(@(x) max(x(2, :), [], 'omitnan'), app.positionTib))];

            axis(app.UIAxes, 'equal');
            xlim(app.UIAxes, [min(xLimits), max(xLimits)]);
            ylim(app.UIAxes, [min(yLimits), max(yLimits)]);
            zlim(app.UIAxes, app.zDisplayRange);

            app.UIAxes.FontSize = 14;
            app.UIAxes.Title.FontSize = 18;
            app.UIAxes.XLabel.FontSize = 16;
            app.UIAxes.YLabel.FontSize = 16;
            app.UIAxes.ZLabel.FontSize = 16;

            app.TimestepSlider.Limits = [1, app.numTimesteps];
            app.TimestepSlider.Value = 1;

            tickStep = max(1, round(app.numTimesteps / 10));
            app.TimestepSlider.MajorTicks = 1:tickStep:app.numTimesteps;

            lgd = legend(app.UIAxes, 'show');
            lgd.FontSize = 28;
            lgd.Location = 'none';
            lgd.Units = 'normalized';
            lgd.Position = [0.73 0.78 0.16 0.12];


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Prompt user to select data directory
            app.dataDir = uigetdir('', 'Select Data Directory');
            if isequal(app.dataDir, 0)
                uialert(app.UIFigure, 'No directory selected', 'Error');
                return; % Exit startup if no directory is selected
            end
            
            % Load data and initialize the 3D plot
            loadData(app);
            if app.numTimesteps > 0
                initializePlot(app);
            else
                uialert(app.UIFigure, 'No data files found', 'Error');
            end
        end

        % Value changed function: TimestepSlider
        function TimestepSliderValueChanged(app, event)
            % timeIndex = round(app.TimestepSlider.Value);  % Get the slider value
            % 
            % % Update the 3D plot to show the new timestep
            % set(app.h, 'XData', app.position(1, :, timeIndex), ...
            %            'YData', app.position(2, :, timeIndex), ...
            %            'ZData', app.position(3, :, timeIndex));
            % 
            % % Update the label to show the current timestep
            % app.DisplayTImestepLabel.Text = ['Timestep: ', num2str(timeIndex)];
        end

        % Value changing function: TimestepSlider
        function TimestepSliderValueChanging(app, event)
             timeIndex = round(event.Value);  % Get the slider value

            % Update the 3D plot to show the new timestep for both Fib and Tib
            if ~isempty(app.hFib)  % Check if Fib points exist
                set(app.hFib, 'XData', app.positionFib{timeIndex}(1, :), ...
                              'YData', app.positionFib{timeIndex}(2, :), ...
                              'ZData', app.positionFib{timeIndex}(3, :));
            end
            
            if ~isempty(app.hTib)  % Check if Tib points exist
                set(app.hTib, 'XData', app.positionTib{timeIndex}(1, :), ...
                              'YData', app.positionTib{timeIndex}(2, :), ...
                              'ZData', app.positionTib{timeIndex}(3, :));
            end
            
            if ~isempty(app.hTib_PoI)  % Check if Tib points exist
                set(app.hTib_PoI, 'XData', app.positionTib_PoI{timeIndex}(1, :), ...
                              'YData', app.positionTib_PoI{timeIndex}(2, :), ...
                              'ZData', app.positionTib_PoI{timeIndex}(3, :));
            end
            
            % Update the label to show the current timestep
            app.DisplayTImestepLabel.Text = ['Timestep: ', num2str(timeIndex)];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            SaveAllFramesButtonPushed(app, event);

            function SaveAllFramesButtonPushed(app, ~)
                % Prompt the user to select a folder for saving the screenshots
                saveFolder = uigetdir('', 'Select Folder to Save Frames');
                if isequal(saveFolder, 0)
                    return; % User canceled
                end

                % Create a progress dialog
                progressDlg = uiprogressdlg(app.UIFigure, ...
                    'Title', 'Saving Frames', ...
                    'Message', 'Initializing...', ...
                    'Cancelable', 'on');

                % Loop through each timestep
                try
                    for t = 1:app.numTimesteps
                        % Check if the user has canceled
                        if progressDlg.CancelRequested
                            error('Frame saving canceled by user.');
                        end

                        % Update progress dialog
                        progressDlg.Value = t / app.numTimesteps;
                        progressDlg.Message = sprintf('Saving frame %d of %d...', t, app.numTimesteps);

                        % Update plots
                        updatePlots(app, t);

                        % Generate a file name
                        fileName = sprintf('frame_%04d.png', t);

                        % Save the plot from the UIAxes
                        exportgraphics(app.UIAxes, fullfile(saveFolder, fileName));
                    end

                    % Notify the user of success
                    progressDlg.Message = 'All frames saved successfully.';
                    pause(1);

                catch ME
                    % Handle cancellation or errors
                    if ~strcmp(ME.message, 'Frame saving canceled by user.')
                        uialert(app.UIFigure, sprintf('Error:\n%s', ME.message), 'Error');
                    end
                end

                % Close the progress dialog
                close(progressDlg);
            end

            function updatePlots(app, timeIndex)
                if ~isempty(app.hFib) && isgraphics(app.hFib)
                    set(app.hFib, ...
                        'XData', app.positionFib{timeIndex}(1, :), ...
                        'YData', app.positionFib{timeIndex}(2, :), ...
                        'ZData', app.positionFib{timeIndex}(3, :));
                end

                if ~isempty(app.hTib) && isgraphics(app.hTib)
                    set(app.hTib, ...
                        'XData', app.positionTib{timeIndex}(1, :), ...
                        'YData', app.positionTib{timeIndex}(2, :), ...
                        'ZData', app.positionTib{timeIndex}(3, :));
                end

                if ~isempty(app.hTib_PoI) && isgraphics(app.hTib_PoI)
                    set(app.hTib_PoI, ...
                        'XData', app.positionTib_PoI{timeIndex}(1, :), ...
                        'YData', app.positionTib_PoI{timeIndex}(2, :), ...
                        'ZData', app.positionTib_PoI{timeIndex}(3, :));
                end

                % Update timestep label
                app.DisplayTImestepLabel.Text = sprintf('Timestep: %d', timeIndex);
                drawnow;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tibia and Fibula during external rotation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TimestepSliderLabel = uilabel(app.UIFigure);
            app.TimestepSliderLabel.Text = 'Timestep';
            app.TimestepSlider = uislider(app.UIFigure);
            app.TimestepSlider.ValueChangedFcn = createCallbackFcn(app, @TimestepSliderValueChanged, true);
            app.TimestepSlider.ValueChangingFcn = createCallbackFcn(app, @TimestepSliderValueChanging, true);
            app.DisplayTImestepLabel = uilabel(app.UIFigure);
            app.DisplayTImestepLabel.Text = 'DisplayTImestep';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionTrackingDisplay_Syndesmose

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end